classdef FRAP_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FRAPUIFigure               matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        ExportMenu                 matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        CorrectdriftMenu           matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        ROITab                     matlab.ui.container.Tab
        AddBleachButton            matlab.ui.control.Button
        AddReferenceButton         matlab.ui.control.Button
        AddBackgroundButton        matlab.ui.control.Button
        LockSwitchLabel            matlab.ui.control.Label
        LockBleachSwitch           matlab.ui.control.Switch
        LockSwitchLabel_2          matlab.ui.control.Label
        LockReferenceSwitch        matlab.ui.control.Switch
        LockSwitchLabel_3          matlab.ui.control.Label
        LockBackgroundSwitch       matlab.ui.control.Switch
        ChanneltouseDropDownLabel  matlab.ui.control.Label
        ChanneltouseDropDown       matlab.ui.control.DropDown
        ExperimentsTab             matlab.ui.container.Tab
        SwitchRawFRAP              matlab.ui.control.Switch
        PrebleachSpinnerLabel      matlab.ui.control.Label
        PrebleachSpinner           matlab.ui.control.Spinner
        NormalizationButtonGroup   matlab.ui.container.ButtonGroup
        NoneButton                 matlab.ui.control.RadioButton
        SingleButton               matlab.ui.control.RadioButton
        DoubleButton               matlab.ui.control.RadioButton
        UIAxesNormEqu              matlab.ui.control.UIAxes
        FitTab                     matlab.ui.container.Tab
        ModelButtonGroup           matlab.ui.container.ButtonGroup
        OnebindingstateButton      matlab.ui.control.RadioButton
        TwobindingstatesButton     matlab.ui.control.RadioButton
        DiffusionButton            matlab.ui.control.RadioButton
        UITableFitData             matlab.ui.control.Table
        TimePanel                  matlab.ui.container.Panel
        TimeSpinner                matlab.ui.control.Spinner
        TimeSlider                 matlab.ui.control.Slider
        UIAxes_Images              matlab.ui.control.UIAxes
        UIAxes_Graphs              matlab.ui.control.UIAxes
        UIAxes_Residuals           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DefaultOptions % Description
        DATA
        hImage %handle of images
        CurrentTidx
        ROI
        FRAP
        w %radius of the bleached spots
    end
    
    methods (Access = private)
        
        function updateTime(app,src,evt)
            switch evt.Source.Type
                case 'uislider'
                    app.CurrentTidx = round(evt.Value);
                    app.TimeSpinner.Value = seconds(app.DATA.t(app.CurrentTidx));
                case 'uispinner'
                    app.CurrentTidx = find(seconds(app.DATA.t) == evt.Value);
                    app.TimeSlider.Value = app.CurrentTidx ;
            end
            
            app.hImage.CData = app.DATA.Stack(:,:,app.CurrentTidx,app.FRAP.idxChUsed);
            
            if isgraphics(app.ROI.Bleach.handle)
                app.ROI.Bleach.handle.Center = app.ROI.Bleach.Center(app.CurrentTidx,:);
            end
            if isgraphics(app.ROI.Reference.handle)
                app.ROI.Reference.handle.Position = app.ROI.Reference.Position(:,:,app.CurrentTidx);
            end
            if isgraphics(app.ROI.Background.handle)
                app.ROI.Background.handle.Position = app.ROI.Background.Position(app.CurrentTidx,:);
            end
            %             if isvalid(app.ROI.Bleach.handle)&isgraphics(app.ROI.Bleach.handle)
            %                 app.ROI.Bleach.handle.Center = app.ROI.Bleach.Center(app.CurrentTidx,:);
            %             end
            %             if isvalid(app.ROI.Reference.handle)&isgraphics(app.ROI.Reference.handle)
            %                 app.ROI.Reference.handle.Position = app.ROI.Reference.Position(:,:,app.CurrentTidx);
            %             end
            %             if isvalid(app.ROI.Background.handle)&isgraphics(app.ROI.Background.handle)
            %                 app.ROI.Background.handle.Position = app.ROI.Background.Position(app.CurrentTidx,:);
            %             end
            drawnow
        end
        
        function  updateROI(app,src,evt)
            
            switch src.Tag
                case 'Bleach'
                    if strcmp(app.(['Lock' src.Tag 'Switch']).Value,'On')
                        app.ROI.(src.Tag).Center = repmat(app.ROI.(src.Tag).handle.Center,[app.DATA.DimT 1]);
                        SelectedTimeIdx = [1:app.DATA.DimT];
                    else
                        app.ROI.(src.Tag).Center(app.CurrentTidx,:) = app.ROI.(src.Tag).handle.Center;
                        SelectedTimeIdx = app.CurrentTidx;
                    end
                    
                case 'Reference'
                    if strcmp(app.(['Lock' src.Tag 'Switch']).Value,'On')
                        app.ROI.(src.Tag).Position = repmat(app.ROI.(src.Tag).handle.Position,[1 1 app.DATA.DimT]);
                        SelectedTimeIdx = [1:app.DATA.DimT];
                    else
                        app.ROI.(src.Tag).Position(:,:,app.CurrentTidx) = app.ROI.(src.Tag).handle.Position;
                        SelectedTimeIdx = app.CurrentTidx;
                    end
                    
                case 'Background'
                    if strcmp(app.(['Lock' src.Tag 'Switch']).Value,'On')
                        xywh = repmat(app.ROI.(src.Tag).handle.Position,[app.DATA.DimT 1]);
                        app.ROI.(src.Tag).Position = xywh;
                        SelectedTimeIdx = [1:app.DATA.DimT];
                    else
                        app.ROI.(src.Tag).Position(app.CurrentTidx,:) = app.ROI.(src.Tag).handle.Position;
                        SelectedTimeIdx = app.CurrentTidx;
                    end
                    
            end
            
            extractSignal(app,[1:app.DATA.DimT],src.Tag);
            updateSignal(app,SelectedTimeIdx,src.Tag)
            if isgraphics(app.FRAP.graph_meas)
                computeFRAP(app)
                updateFRAP(app)
            end
            
        end
        
        function extractSignal(app,SelectedTimeIdx,RoiType)
            
            
            %             idxC = find(ismember(app.ChanneltouseDropDown.Value,app.ChanneltouseDropDown.Items));
            for idxT = SelectedTimeIdx
                im = app.DATA.Stack(:,:,idxT,app.FRAP.idxChUsed);
                im = im(createMask(app.ROI.(RoiType).handle));
                app.ROI.(RoiType).Signal(idxT) = mean(im(:));
            end
        end
        
        function updateSignal(app,SelectedTimeIdx,RoiType)
            app.ROI.(RoiType).graph.YData(SelectedTimeIdx) = app.ROI.(RoiType).Signal(SelectedTimeIdx);
            app.ROI.(RoiType).graph.XData = seconds(app.DATA.t-app.DATA.t(app.FRAP.PreBleachTimeIdx+1));
            
            
            %             line(app.UIAxes_Graphs,seconds(app.DATA.t),...
            %             app.ROI.(RoiType).signal()
        end
        
        function computeFRAP(app)
            switch app.FRAP.Normalization
                case 'None'
                    bleach = app.ROI.Bleach.Signal;
                    pre    = mean(bleach(1:app.FRAP.PreBleachTimeIdx));
                    frap   = bleach/pre;
                case 'Single'
                    bleach     = app.ROI.Bleach.Signal;
                    background = app.ROI.Background.Signal;
                    pre_bleach = mean(bleach(1:app.FRAP.PreBleachTimeIdx));
                    pre_back   = mean(background(1:app.FRAP.PreBleachTimeIdx));
                    frap = (bleach-background)/(pre_bleach-pre_back);
                    
                case 'Double'
                    bleach     = app.ROI.Bleach.Signal;
                    background = app.ROI.Background.Signal;
                    reference  = app.ROI.Reference.Signal;
                    pre_bleach = mean(bleach(1:app.FRAP.PreBleachTimeIdx));
                    pre_back   = mean(background(1:app.FRAP.PreBleachTimeIdx));
                    pre_ref    = mean(reference(1:app.FRAP.PreBleachTimeIdx));
                    frap = (pre_ref-pre_back)./(reference-background).*(bleach-background)/(pre_bleach-pre_back);
            end
            
            app.FRAP.measurement = frap;
            
        end
        
        function resetFRAP(app)
            app.w = 1.3;%1.5;
            app.CurrentTidx = 1;
            app.UIAxesNormEqu.Title.String ='';
            im = app.DATA.OriginalStack(:,:,:,app.FRAP.idxChUsed);
            im = im(:);
            app.hImage = imshow(app.DATA.OriginalStack(:,:,app.CurrentTidx,app.FRAP.idxChUsed),[min(im) max(im)],'Parent',app.UIAxes_Images);
            
            
            if isempty(app.ROI.Bleach.handle) %-> throught open image
                app.ROI.Bleach.handle     = images.roi.Circle;
                app.ROI.Reference.handle  = images.roi.Freehand;
                app.ROI.Background.handle = images.roi.Rectangle;
                delete(app.ROI.Bleach.handle);
                delete(app.ROI.Reference.handle);
                delete(app.ROI.Background.handle);
                
                app.ROI.Bleach.graph     = scatter(app.UIAxes_Graphs,nan(1,1),nan(1,1));
                app.ROI.Reference.graph  = scatter(app.UIAxes_Graphs,nan(1,1),nan(1,1));
                app.ROI.Background.graph = scatter(app.UIAxes_Graphs,nan(1,1),nan(1,1));
                app.FRAP.graph_meas      = scatter(app.UIAxes_Graphs,nan(1,1),nan(1,1));
                app.FRAP.graph_fit       = scatter(app.UIAxes_Graphs,nan(1,1),nan(1,1));
                app.FRAP.graph_residuals = scatter(app.UIAxes_Residuals,nan(1,1),nan(1,1));
                delete(app.ROI.Bleach.graph)
                delete(app.ROI.Reference.graph)
                delete(app.ROI.Background.graph)
                delete(app.FRAP.graph_meas)
                delete(app.FRAP.graph_fit)
                delete(app.FRAP.graph_residuals)
            else %-> throught load data
                hold(app.UIAxes_Graphs,'on')
                %__________________________________________________________
                if ~isempty(app.ROI.Bleach.Center)
                    c_color = [1 0 0];
                    app.ROI.Bleach.handle = images.roi.Circle(app.UIAxes_Images,...
                        'Color',c_color,'StripeColor','none',...
                        'LineWidth',1,'MarkerSize',2,...
                        'InteractionsAllowed','translate',...
                        'Center',app.ROI.Bleach.Center(app.CurrentTidx,:),...
                        'Radius',1.5/app.DATA.ResX,...
                        'Tag','Bleach');
                    app.ROI.Bleach.graph = scatter(app.UIAxes_Graphs,...
                        seconds(app.DATA.t-app.DATA.t(app.FRAP.PreBleachTimeIdx+1)),app.ROI.Bleach.Signal,...
                        'Marker','o','MarkerFaceColor',c_color,'MarkerFaceAlpha',0.5,...
                        'MarkerEdgeColor',0.2*c_color,'MarkerEdgeAlpha',1,...
                        'SizeData',5);
                    
                    addlistener(app.ROI.Bleach.handle, 'MovingROI', @(src,evt)updateROI(app,src,evt));
                    addlistener(app.ROI.Bleach.handle, 'ROIMoved', @(src,evt)updateFitAfterROIMoved(app,src,evt));
                else
                    app.ROI.Bleach.handle     = images.roi.Circle;
                    delete(app.ROI.Bleach.handle);
                    app.ROI.Bleach.graph     = scatter(app.UIAxes_Graphs,nan(1,1),nan(1,1));
                    delete(app.ROI.Bleach.graph)
                end
                %__________________________________________________________
                if ~isempty(app.ROI.Reference.Position)
                    c_color = [0 1 0];
                    app.ROI.Reference.handle = images.roi.Freehand(app.UIAxes_Images,...
                        'Color',c_color,'StripeColor','none',...
                        'LineWidth',1,'MarkerSize',2,...
                        'InteractionsAllowed','translate',...
                        'Position',app.ROI.Reference.Position(:,:,app.CurrentTidx),...
                        'Tag','Reference');
                    app.ROI.Reference.graph = scatter(app.UIAxes_Graphs,...
                        seconds(app.DATA.t-app.DATA.t(app.FRAP.PreBleachTimeIdx+1)),app.ROI.Reference.Signal,...
                        'Marker','o','MarkerFaceColor',c_color,'MarkerFaceAlpha',0.5,...
                        'MarkerEdgeColor',0.2*c_color,'MarkerEdgeAlpha',1,...
                        'SizeData',5);
                    addlistener(app.ROI.Reference.handle, 'MovingROI', @(src,evt)updateROI(app,src,evt));
                    addlistener(app.ROI.Reference.handle, 'ROIMoved', @(src,evt)updateFitAfterROIMoved(app,src,evt));
                else
                    app.ROI.Reference.handle     = images.roi.Freehand;
                    delete(app.ROI.Reference.handle);
                    app.ROI.Reference.graph     = scatter(app.UIAxes_Graphs,nan(1,1),nan(1,1));
                    delete(app.ROI.Reference.graph)
                end
                %__________________________________________________________
                if ~isempty(app.ROI.Background.Position)
                    c_color = [0 0 1];
                    app.ROI.Background.handle = images.roi.Rectangle(app.UIAxes_Images,...
                        'Color',c_color,'StripeColor','none',...
                        'LineWidth',1,'MarkerSize',2,...
                        'InteractionsAllowed','translate',...
                        'Position',app.ROI.Background.Position(app.CurrentTidx,:),...
                        'Tag','Background');
                    app.ROI.Background.graph = scatter(app.UIAxes_Graphs,...
                        seconds(app.DATA.t-app.DATA.t(app.FRAP.PreBleachTimeIdx)),app.ROI.Background.Signal,...
                        'Marker','o','MarkerFaceColor',c_color,'MarkerFaceAlpha',0.5,...
                        'MarkerEdgeColor',0.2*c_color,'MarkerEdgeAlpha',1,...
                        'SizeData',5);
                    addlistener(app.ROI.Background.handle, 'MovingROI', @(src,evt)updateROI(app,src,evt));
                    addlistener(app.ROI.Background.handle, 'ROIMoved', @(src,evt)updateFitAfterROIMoved(app,src,evt));
                else
                    app.ROI.Background.handle     = images.roi.Rectangle;
                    delete(app.ROI.Background.handle);
                    app.ROI.Background.graph     = scatter(app.UIAxes_Graphs,nan(1,1),nan(1,1));
                    delete(app.ROI.Background.graph)
                end
                %__________________________________________________________
                if ~isempty(app.FRAP.measurement)
                    app.PrebleachSpinner.Value = seconds(app.DATA.t(app.FRAP.PreBleachTimeIdx));
                    app.FRAP.graph_meas = scatter(app.UIAxes_Graphs,...
                        seconds(app.DATA.t-app.DATA.t(app.FRAP.PreBleachTimeIdx)), app.FRAP.measurement,...
                        'Marker','o','MarkerFaceColor',[1 0 0],'MarkerFaceAlpha',0.5,...
                        'MarkerEdgeColor',0.2*[1 0 0],'MarkerEdgeAlpha',1,...
                        'SizeData',5,'Visible',"off");
                    switch app.FRAP.Normalization
                        case 'None'
                            app.NoneButton.Value = 1;
                            title(app.UIAxesNormEqu, ...
                                ['$$\mathrm {FRAP}\left(t\right) = \frac{\mathrm {Bleach}\left(t\right)}{\mathrm {Bleach}_{\textrm{Pre}}}$$'],...
                                "Interpreter","latex",'HorizontalAlignment',"center")
                        case 'Single'
                            app.SingleButton.Value = 1;
                            title(app.UIAxesNormEqu, ...
                                ['$$\mathrm {FRAP}\left(t\right) = \frac{\mathrm{Bleach}\left(t\right) - \mathrm{BKGD}\left(t\right)}{\mathrm{Bleach}_{\textrm{Pre}} - \mathrm{BKGD}_{\textrm{Pre}} }$$'],...
                                "Interpreter","latex",'HorizontalAlignment',"center");%,'HorizontalAlignment',"right")
                        case 'Double'
                            app.DoubleButton.Value = 1;
                            title(app.UIAxesNormEqu, ...
                                ['$$\mathrm {FRAP}\left(t\right) = \frac{\mathrm{Bleach}\left(t\right) - \mathrm{BKGD}\left(t\right)}{\mathrm{Bleach}_{\textrm{Pre}} - \mathrm{BKGD}_{\textrm{Pre}} }\cdot\frac{\mathrm{Ref}_{\textrm{Pre}} - \mathrm{BKGD}_{\textrm{Pre}}}{\mathrm{Ref}\left(t\right) - \mathrm{BKGD}\left(t\right)}$$'],...
                                "Interpreter","latex",'HorizontalAlignment',"center");
                            
                    end
                else
                    app.FRAP.graph_meas = scatter(app.UIAxes_Graphs,nan(1,1),nan(1,1));
                    delete(app.FRAP.graph_meas)
                end
                %__________________________________________________________
                if ~isempty(app.FRAP.fit.func)
                    t = seconds(app.DATA.t);
                    t = t-t(app.FRAP.PreBleachTimeIdx+1);
                    idxKeep = t>=0;
                    t = t(idxKeep);
                    app.FRAP.graph_fit = line(app.UIAxes_Graphs,...
                        t, app.FRAP.fit.func(t),...
                        'LineStyle','-','LineWidth',2,'Color',[0 0 0],...
                        'Visible',"off");
                    
                    app.FRAP.graph_residuals = scatter(app.UIAxes_Residuals,...
                        t, app.FRAP.fit.residuals,...
                        'Marker','o','MarkerFaceColor',c_color,'MarkerFaceAlpha',0.5,...
                        'MarkerEdgeColor',0.2*c_color,'MarkerEdgeAlpha',1,...
                        'SizeData',5,'Visible',"off");
                else
                    app.FRAP.graph_fit = line(app.UIAxes_Graphs,nan(1),nan(1));
                    app.FRAP.graph_residuals = scatter(app.UIAxes_Residuals,nan(1),nan(1));
                    delete(app.FRAP.graph_fit)
                    delete(app.FRAP.graph_residuals)
                    
                end
                if ~isempty(app.FRAP.fit.Param)
                    switch app.FRAP.fit.Model
                        case 'One binding states'
                            headers = {'Bleach depth';...
                                'Half-time (s)';...
                                'Mobile fraction';...
                                'Binding rate (1/s)';...
                                '';...
                                'adjusted-R2';
                                'Chi2';
                                'Q'};
                            data{1,1} =  app.FRAP.fit.Param.BleachDepth;
                            data{2,1} =  app.FRAP.fit.Param.HalfLife;
                            data{3,1} =  app.FRAP.fit.Param.MobileFrac;
                            data{4,1} =  app.FRAP.fit.Param.BindingRate;
                            
                            data{6,1} =  app.FRAP.fit.gof.adjrsquare;
                            data{7,1} =  app.FRAP.fit.Param.Chi2;
                            data{8,1} =  app.FRAP.fit.Param.Q;
                            app.UITableFitData.Data = [headers data];
                            app.OnebindingstateButton.Value = 1;
                        case 'Two binding states'
                            headers = {'Bleach depth';...
                                'Half-time (s)';...
                                'Mobile fraction slow';...
                                'Binding rate slow (1/s)';...
                                'Mobile fraction fast';...
                                'Binding rate fast (1/s)';...
                                '';...
                                'adjusted-R2';
                                'Chi2';
                                'Q'};
                            
                            data{1,1} =  app.FRAP.fit.Param.BleachDepth;
                            data{2,1} =  app.FRAP.fit.Param.HalfLife;
                            data{3,1} =  app.FRAP.fit.Param.MobileFrac1;
                            data{4,1} =  app.FRAP.fit.Param.BindingRate1;
                            data{5,1} =  app.FRAP.fit.Param.MobileFrac2;
                            data{6,1} =  app.FRAP.fit.Param.BindingRate2;
                            
                            data{8,1} =  app.FRAP.fit.gof.adjrsquare;
                            data{9,1} =  app.FRAP.fit.Param.Chi2;
                            data{10,1} =  app.FRAP.fit.Param.Q;
                            app.UITableFitData.Data = [headers data];
                            app.TwobindingstatesButton.Value = 1;
                        case 'Diffusion'
                            headers = {'Half-time (s)';...
                                'Mobile fraction';...
                                'Diffusion time (s)';...
                                ['Diffusion coeff (' char(181) 'm' char(178) '/s)'];...
                                '';...
                                'adjusted-R2';
                                'Chi2';
                                'Q'};
                            
                            data{1,1} =  app.FRAP.fit.Param.BleachDepth;
                            data{2,1} =  app.FRAP.fit.Param.HalfLife;
                            data{3,1} =  app.FRAP.fit.Param.tauD;
                            data{4,1} =  app.FRAP.fit.Param.D;
                            
                            data{6,1} =  app.FRAP.fit.gof.adjrsquare;
                            data{7,1} =  app.FRAP.fit.Param.Chi2;
                            data{8,1} =  app.FRAP.fit.Param.Q;
                            app.UITableFitData.Data = [headers data];
                            app.DiffusionButton.Value = 1;
                    end
                end


                hold(app.UIAxes_Graphs,'off')
            end
            
            app.NoneButton.Enable = false;
            app.SingleButton.Enable = false;
            app.DoubleButton.Enable = false;
            if isgraphics(app.ROI.Bleach.handle)
                app.NoneButton.Enable = true;
            end
            if isgraphics(app.ROI.Bleach.handle)&&isgraphics(app.ROI.Background.handle)
                app.SingleButton.Enable = true;
            end
            if isgraphics(app.ROI.Bleach.handle)&&isgraphics(app.ROI.Background.handle)&&isgraphics(app.ROI.Reference.handle)
                app.DoubleButton.Enable = true;
            end
            
            
            axis(app.UIAxesNormEqu,'off')
            
            %Into an initialization methods maybe...
            app.TimeSlider.Limits  = [1 app.DATA.DimT];
            app.TimeSlider.MajorTicks = [];
            app.TimeSpinner.Limits = [seconds(app.DATA.t(1)) seconds(app.DATA.t(end))];
            app.TimeSpinner.Step = seconds(app.DATA.ResT);
            app.UIAxes_Images.Title.String = app.DATA.Name;
            app.UIAxes_Images.Title.Interpreter = 'none';
            
            app.LockBleachSwitch.Value     = 'On';
            app.LockReferenceSwitch.Value  = 'On';
            app.LockBackgroundSwitch.Value = 'On';
            
            
            app.ChanneltouseDropDown.Items = app.DATA.ChannelName;
            app.ChanneltouseDropDown.Value = app.DATA.ChannelName(app.FRAP.idxChUsed);
            app.PrebleachSpinner.Limits = seconds([app.DATA.t(1) app.DATA.t(end)]);
            app.PrebleachSpinner.Step   = seconds(app.DATA.ResT);
            %             linkaxes([app.UIAxes_Graphs,app.UIAxes_Residuals],'x')
            app.UIAxes_Graphs.XLim    = seconds([app.DATA.t(1) app.DATA.t(end)]-app.DATA.t(app.FRAP.PreBleachTimeIdx+1));
            app.UIAxes_Residuals.XLim = seconds([app.DATA.t(1) app.DATA.t(end)]-app.DATA.t(app.FRAP.PreBleachTimeIdx+1));
            linkaxes([app.UIAxes_Graphs,app.UIAxes_Residuals],'x')
            
        end
        
        function updateFRAP(app)
            app.FRAP.graph_meas.YData = app.FRAP.measurement;
            app.FRAP.graph_meas.XData = seconds(app.DATA.t-app.DATA.t(app.FRAP.PreBleachTimeIdx+1));
        end
        
        function fitModel(app)
            hwb = uiprogressdlg(app.FRAPUIFigure,'Title',['Fitting recovery curve...'],...
                'Indeterminate','on');
            
            selectedButton = app.ModelButtonGroup.SelectedObject;
            app.FRAP.fit.Model = selectedButton.Text;
            
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';%'final' 'iter'
            opts.TolFun = 10^-10;
            opts.TolX   = 10^-10;
            opts.MaxFunEvals = 10^4;
            opts.MaxIter = 10^4;
            
            switch app.FRAP.fit.Model
                case 'One binding state'
                    %                     FrapModel = fittype( '(1-r)*(1-c*exp(-k*t))',...
                    %                         'independent', 't', 'dependent', 'y',...
                    %                         'coefficients',{'r','c','k'});
                    FrapModel = fittype( 'Fi-dF+dF*mf*(1-exp(-l*t))',...
                        'independent', 't', 'dependent', 'y',...
                        'coefficients',{'Fi','dF','mf','l'});
                    opts.StartPoint =[ 1  0.8  0.5    0.01];
                    opts.Lower =     [ 0  0    0      0   ];
                    opts.Upper	=    [ 2  1    1   1000   ];
                    
                    
                case 'Two binding states'
                    %                     FrapModel = fittype( '(1-r)*(1-c1*exp(-k1*t)-c2*exp(-k2*t))',...
                    %                         'independent', 't', 'dependent', 'y',...
                    %                         'coefficients',{'r','c1','k1','c2','k2'});
                    FrapModel = fittype( 'Fi-dF+dF*mf1*(1-exp(-l1*t))+dF*mf2*(1-exp(-l2*t)) ',...
                        'independent', 't', 'dependent', 'y',...
                        'coefficients',{'Fi','dF','mf1','l1','mf2','l2'});
                    opts.StartPoint =[ 1  0.8  0.5    0.01  0.5    0.01];
                    opts.Lower =     [ 0  0    0      0     0      0   ];
                    opts.Upper	=    [ 2  1    1   1000     1   1000   ];
                case 'Diffusion'
                    FrapModel = fittype( 'a0+a1*exp(-tau./(2*t)).*(besseli(0,tau./(2*t))+besseli(1,tau./(2*t)))',...
                        'independent', 't', 'dependent', 'y',...
                        'coefficients',{'a0','a1','tau'});
                    %                     FrapModel = fittype( 'a0+a1*exp(l./(2*t)',...
                    %                         'independent', 't', 'dependent', 'y',...
                    %                         'coefficients',{'a0','a1','l'});
            end
            
            t = seconds(app.DATA.t);
            t = t-t(app.FRAP.PreBleachTimeIdx+1);
            idxKeep = t>=0;
            t = t(idxKeep);
            y = app.FRAP.measurement(idxKeep);
            app.FRAP.fit.Param = [];
            s = std(app.FRAP.measurement(~idxKeep),0);
            try
                [fit_func,gof,output] = fit(t',y',FrapModel,opts);
                app.FRAP.fit.func = fit_func;
                app.FRAP.fit.gof = gof;
                app.FRAP.fit.residuals = output.residuals;
                
                thlf = [0:0.001:max(t)];
                f0 = app.FRAP.fit.func.Fi-app.FRAP.fit.func.dF/2;
                
                [~,idx] = min(abs(app.FRAP.fit.func(thlf)-f0));
                app.FRAP.fit.Param.HalfLife = thlf(idx);
                app.FRAP.fit.Param.BleachDepth = app.FRAP.fit.func.dF/app.FRAP.fit.func.Fi;
                
                switch app.FRAP.fit.Model
                    case 'One binding state'
                        app.FRAP.fit.Param.MobileFrac  = app.FRAP.fit.func.mf;
                        app.FRAP.fit.Param.BindingRate = app.FRAP.fit.func.l;
                    case 'Two binding states'
                        l  = [app.FRAP.fit.func.l1,  app.FRAP.fit.func.l2];
                        mf = [app.FRAP.fit.func.mf1, app.FRAP.fit.func.mf2];
                        [~,idx] = sort(l);
                        app.FRAP.fit.Param.MobileFrac1  = mf(idx(1));
                        app.FRAP.fit.Param.BindingRate1 = l(idx(1));
                        app.FRAP.fit.Param.MobileFrac2  = mf(idx(2));
                        app.FRAP.fit.Param.BindingRate2 = l(idx(2));
                    case 'Diffusion'
                        app.FRAP.fit.Param.tauD = app.FRAP.fit.func.tau;
                        app.FRAP.fit.Param.D    = app.w^2/app.FRAP.fit.Param.TauD;
                end
                %                 Chi2 = sum((y'-app.FRAP.fit.func(t)).^2);
                Chi2 = sum(((y'-app.FRAP.fit.func(t))/s).^2);
                Q    = gammainc( Chi2 /2,app.FRAP.fit.gof.dfe/2,'upper');
                app.FRAP.fit.Param.Chi2 = Chi2;
                app.FRAP.fit.Param.Q    = Q;
                app.FRAP.fit.Param.adjrsquare    = app.FRAP.fit.gof.adjrsquare;
            catch
                warning([ app.FRAP.fit.Model ' fit did not converged'])
                app.FRAP.fit.func = [];
                app.FRAP.fit.gof  = [];
                app.FRAP.fit.residuals = [];
            end
            
            
            %                         opts.StartPoint =[ 50  0.001 50  0.001  10];
            %                         opts.Lower =     [  0  0      0  0       0];
            %                         opts.Upper	=    [100  1    100  1     100];
            close(hwb)
        end
        
        function updateFit(app)
            t = seconds(app.DATA.t);
            t = t-t(app.FRAP.PreBleachTimeIdx+1);
            t = t(t>=0);
            if ~isempty(app.FRAP.fit.func)
                app.FRAP.graph_fit.YData = app.FRAP.fit.func(t);
                app.FRAP.graph_fit.XData = t;
                app.FRAP.graph_residuals.YData = app.FRAP.fit.residuals;
                app.FRAP.graph_residuals.XData = t;
            else
                app.FRAP.graph_fit.YData = nan(size(t));
                app.FRAP.graph_fit.XData = t;
                app.FRAP.graph_residuals.YData = nan(size(t));
                app.FRAP.graph_residuals.XData = t;
            end
            
        end
        
        function updateFitAfterROIMoved(app,src,evt)
            
            if isgraphics(app.FRAP.graph_fit)
                
                fitModel(app);
                updateFit(app);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            
            app.FRAPUIFigure.Visible = 'off';
            cd(fileparts(which('FRAP_analysis.mlapp')))
            addpath(genpath('mfiles'))
            bfInitLogging();
            bfUpgradeCheck(true);
            tmp = load("mfiles\DefaultOptions.mat");
            app.DefaultOptions = tmp.DefaultOptions;
            addlistener(app.TimeSlider, 'ValueChanging', @(src,evt) app.updateTime(src,evt));
            addlistener(app.TimeSpinner, 'ValueChanged', @(src,evt) app.updateTime(src,evt));
            
            
            movegui(app.FRAPUIFigure,"center")
            app.FRAPUIFigure.Visible = 'on';
            
            disableDefaultInteractivity(app.UIAxes_Images)
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %InitializeGUI(app)
            [FileName,FilePath] = uigetfile({'*.nd2','Nikon files'},...
                'Select a FRAP experiment',...
                app.DefaultOptions.LastFolderPath,...
                'MultiSelect', 'off');
            if isnumeric(FileName)
                return
            end
            app.DefaultOptions.LastFolderPath = FilePath;
            DefaultOptions = app.DefaultOptions;
            save("mfiles\DefaultOptions.mat",'DefaultOptions')
            
            hwb = uiprogressdlg(app.FRAPUIFigure,'Title',['Loading ' FileName],...
                'Indeterminate','on');
            
            %
            app.DATA = nd2read(FilePath,FileName);
            app.DATA.t = [0:app.DATA.DimT-1]*app.DATA.ResT;
            app.DATA.Stack = app.DATA.OriginalStack;
            
            app.ROI.Bleach.handle       = [];
            app.ROI.Bleach.graph        = [];
            app.ROI.Bleach.Signal       = [];
            app.ROI.Bleach.Center       = [];
            app.ROI.Reference.handle    = [];
            app.ROI.Reference.graph     = [];
            app.ROI.Reference.Signal    = [];
            app.ROI.Reference.Position  = [];
            app.ROI.Background.handle   = [];
            app.ROI.Background.graph    = [];
            app.ROI.Background.Signal   = [];
            app.ROI.Background.Position = [];
            
            app.FRAP.idxChUsed     = 1;
            app.FRAP.Normalization = 'None';
            app.FRAP.PreBleachTimeIdx = 3;
            app.FRAP.measurement = [];
            
            app.FRAP.fit.Model = [];
            app.FRAP.fit.func  = [];
            app.FRAP.fit.gof   = [];
            app.FRAP.fit.residuals = [];
            
            app.FRAP.graph_meas      = [];
            app.FRAP.graph_fit       = [];
            app.FRAP.graph_residuals = [];
            
            resetFRAP(app)
            close(hwb)
            %
        end

        % Callback function
        function TimeValueChanging(app, event)
            app.CurrentTidx = round(event.Value);
            %             updatetime(app)
        end

        % Button pushed function: AddBackgroundButton, 
        % AddBleachButton, AddReferenceButton
        function AddROIButtonPushed(app, event)
            
            
            switch event.Source.Tag
                case 'Bleach'
                    c_color = [1 0 0];
                    RoiType = 'Bleach';
                    if isgraphics(app.ROI.(RoiType).handle)
                        delete(app.ROI.(RoiType).handle)
                        delete(app.ROI.(RoiType).graph)
                    end
                    
                    app.ROI.(RoiType).Center = repmat([app.DATA.DimX app.DATA.DimY]/2,[app.DATA.DimT 1]);
                    app.ROI.(RoiType).handle = images.roi.Circle(app.UIAxes_Images,...
                        'Color',c_color,'StripeColor','none',...
                        'LineWidth',1,'MarkerSize',2,...
                        'InteractionsAllowed','translate',...
                        'Center',app.ROI.(RoiType).Center(app.CurrentTidx,:),...
                        'Radius',app.w/app.DATA.ResX,...
                        'Tag',RoiType);
                    
                case 'Background'
                    c_color = [0 0 1];
                    RoiType = 'Background';
                    if isgraphics(app.ROI.(RoiType).handle)
                        delete(app.ROI.(RoiType).handle)
                        delete(app.ROI.(RoiType).graph)
                    end
                    
                    app.ROI.(RoiType).Position = repmat([[app.DATA.DimX app.DATA.DimY]/2 [3 3]/app.DATA.ResX],[app.DATA.DimT 1]);
                    app.ROI.(RoiType).handle = images.roi.Rectangle(app.UIAxes_Images,...
                        'Color',c_color,'StripeColor','none',...
                        'LineWidth',1,'MarkerSize',2,...
                        'InteractionsAllowed','translate',...
                        'Position',app.ROI.(RoiType).Position(app.CurrentTidx,:),...
                        'Tag',RoiType);
                    
                case 'Reference'
                    c_color = [0 1 0];
                    RoiType = 'Reference';
                    if isgraphics(app.ROI.(RoiType).handle)
                        delete(app.ROI.(RoiType).handle)
                        delete(app.ROI.(RoiType).graph)
                    end
                    app.ROI.(RoiType).handle = images.roi.Freehand(app.UIAxes_Images,...
                        'Color',c_color,'StripeColor','none',...
                        'LineWidth',1,'MarkerSize',2,...
                        'InteractionsAllowed','translate',...
                        'Tag',RoiType);
                    draw(app.ROI.(RoiType).handle)
                    app.ROI.(RoiType).Position = repmat(app.ROI.(RoiType).handle.Position,[1 1 app.DATA.DimT]);
                    
            end
            
            addlistener(app.ROI.(RoiType).handle, 'MovingROI', @(src,evt)updateROI(app,src,evt));
            addlistener(app.ROI.(RoiType).handle, 'ROIMoved', @(src,evt)updateFitAfterROIMoved(app,src,evt));
            
            
            extractSignal(app,[1:app.DATA.DimT],RoiType);
            
            app.SwitchRawFRAP.Value = 'Raw';
            
            hold(app.UIAxes_Graphs,'on')
            app.ROI.(RoiType).graph = scatter(app.UIAxes_Graphs,...
                seconds(app.DATA.t-app.DATA.t(app.FRAP.PreBleachTimeIdx+1)),app.ROI.(RoiType).Signal,...
                'Marker','o','MarkerFaceColor',c_color,'MarkerFaceAlpha',0.5,...
                'MarkerEdgeColor',0.2*c_color,'MarkerEdgeAlpha',1,...
                'SizeData',5);
            hold(app.UIAxes_Graphs,'off')
            if isgraphics(app.ROI.Bleach.graph)
                app.ROI.Bleach.graph.Visible = 'on';
            end
            if isgraphics(app.ROI.Reference.graph)
                app.ROI.Reference.graph.Visible = 'on';
            end
            if isgraphics(app.ROI.Background.graph)
                app.ROI.Background.graph.Visible = 'on';
            end
            
            if isgraphics(app.ROI.Bleach.handle)
                app.NoneButton.Enable = true;
            end
            
            if isgraphics(app.ROI.Bleach.handle)&isgraphics(app.ROI.Background.handle)
                app.SingleButton.Enable = true;
            end
            
            if isgraphics(app.ROI.Bleach.handle)&isgraphics(app.ROI.Background.handle)&isgraphics(app.ROI.Reference.handle)
                app.DoubleButton.Enable = true;
            end
            
            
            %             app.ROI.(RoiType).graph = scatter(app.UIAxes_Graphs,...
            %                 seconds(app.DATA.t)-app.PrebleachSpinner.Value,app.ROI.(RoiType).Signal,...
            %                 'LineStyle','none','Marker','o','MarkerFaceColor',c_color,...
            %                 'MarkerEdgeColor',0.5*c_color);
            
            
            if isgraphics(app.ROI.Bleach.handle)
                
                computeFRAP(app)
                fitModel(app)
                
                hold(app.UIAxes_Graphs,'on')
                if isgraphics(app.FRAP.graph_meas)
                    delete(app.FRAP.graph_meas)
                end
                app.FRAP.graph_meas = scatter(app.UIAxes_Graphs,...
                    seconds(app.DATA.t-app.DATA.t(app.FRAP.PreBleachTimeIdx+1)), app.FRAP.measurement,...
                    'Marker','o','MarkerFaceColor',[1 0 0],'MarkerFaceAlpha',0.5,...
                    'MarkerEdgeColor',0.2*[1 0 0],'MarkerEdgeAlpha',1,...
                    'SizeData',5,'Visible',"off");
                
                
                t = seconds(app.DATA.t);
                t = t-t(app.FRAP.PreBleachTimeIdx+1);
                idxKeep = t>=0;
                t = t(idxKeep);
                if isgraphics(app.FRAP.graph_fit)
                    delete(app.FRAP.graph_fit)
                    delete(app.FRAP.graph_residuals)
                end
                
                if isempty(app.FRAP.fit.func)
                    app.FRAP.graph_fit = line(app.UIAxes_Graphs,...
                        t, nan(size(t)),...
                        'LineStyle','-','LineWidth',2,'Color',[0 0 0],...
                        'Visible',"off");
                    
                    app.FRAP.graph_residuals = scatter(app.UIAxes_Residuals,...
                        t, nan(size(t)),...
                        'Marker','o','MarkerFaceColor',c_color,'MarkerFaceAlpha',0.5,...
                        'MarkerEdgeColor',0.2*c_color,'MarkerEdgeAlpha',1,...
                        'SizeData',5,'Visible',"off");
                    
                else
                    app.FRAP.graph_fit = line(app.UIAxes_Graphs,...
                        t, app.FRAP.fit.func(t),...
                        'LineStyle','-','LineWidth',2,'Color',[0 0 0],...
                        'Visible',"off");
                    
                    app.FRAP.graph_residuals = scatter(app.UIAxes_Residuals,...
                        t, app.FRAP.fit.residuals,...
                        'Marker','o','MarkerFaceColor',c_color,'MarkerFaceAlpha',0.5,...
                        'MarkerEdgeColor',0.2*c_color,'MarkerEdgeAlpha',1,...
                        'SizeData',5,'Visible',"off");
                    
                end
                hold(app.UIAxes_Graphs,'off')
                
                app.UIAxes_Residuals.Visible = 'off';
                
                
            end


            %             beginDrawingFromPoint(app.ROI.(RoiType).handle,[100 100])
            %  draw(app.ROI.(RoiType).handle)
            
            
            %             updateSignal(app,[1:app.DATA.DimT],RoiType);
            %             extractSignal(app,[1:app.DATA.DimT],RoiType)
            
        end

        % Menu selected function: CorrectdriftMenu
        function CorrectdriftMenuSelected(app, event)
            app.DefaultOptions.registration_para.idxTREF = 1;
            app.DefaultOptions.registration_para.idxCREF = 1;
            hwb = uiprogressdlg(app.FRAPUIFigure,'Title','Registration',...
                'Message','Forward registration');
            app.DATA = par_stackreg(app.DATA,app.DefaultOptions.registration_para,hwb);
            if isgraphics(app.ROI.Bleach.graph)
                extractSignal(app,[1:app.DATA.DimT],'Bleach')
                updateSignal(app,[1:app.DATA.DimT],'Bleach')
            end
            if isgraphics(app.ROI.Reference.graph)
                extractSignal(app,[1:app.DATA.DimT],'Reference')
                updateSignal(app,[1:app.DATA.DimT],'Reference')
            end
            if isgraphics(app.ROI.Background.graph)
                extractSignal(app,[1:app.DATA.DimT],'Background')
                updateSignal(app,[1:app.DATA.DimT],'Background')
            end
            
            if isgraphics(app.FRAP.graph_meas)
                computeFRAP(app)
                updateFRAP(app)
                fitModel(app)
                updateFit(app)
            end
            close(hwb)
        end

        % Selection changed function: NormalizationButtonGroup
        function NormalizationButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormalizationButtonGroup.SelectedObject;
            app.FRAP.Normalization = selectedButton.Text;
            %             app.HTMLNormalization.HTMLSource = 'new 1.html';%'<p style="font-family:arial;"><b><span style="color:red;">Hello</span> <u>World</u>!</b></p>';
            axis(app.UIAxesNormEqu,'off')
            switch app.FRAP.Normalization
                case 'None'
                    title(app.UIAxesNormEqu, ...
                        ['$$\mathrm {FRAP}\left(t\right) = \frac{\mathrm {Bleach}\left(t\right)}{\mathrm {Bleach}_{\textrm{Pre}}}$$'],...
                        "Interpreter","latex",'HorizontalAlignment',"center")
                case 'Single'
                    title(app.UIAxesNormEqu, ...
                        ['$$\mathrm {FRAP}\left(t\right) = \frac{\mathrm{Bleach}\left(t\right) - \mathrm{BKGD}\left(t\right)}{\mathrm{Bleach}_{\textrm{Pre}} - \mathrm{BKGD}_{\textrm{Pre}} }$$'],...
                        "Interpreter","latex",'HorizontalAlignment',"center");%,'HorizontalAlignment',"right")
                case 'Double'
                    title(app.UIAxesNormEqu, ...
                        ['$$\mathrm {FRAP}\left(t\right) = \frac{\mathrm{Bleach}\left(t\right) - \mathrm{BKGD}\left(t\right)}{\mathrm{Bleach}_{\textrm{Pre}} - \mathrm{BKGD}_{\textrm{Pre}} }\cdot\frac{\mathrm{Ref}_{\textrm{Pre}} - \mathrm{BKGD}_{\textrm{Pre}}}{\mathrm{Ref}\left(t\right) - \mathrm{BKGD}\left(t\right)}$$'],...
                        "Interpreter","latex",'HorizontalAlignment',"center");%,'HorizontalAlignment',"left")
                    
            end
            
            computeFRAP(app)
            updateFRAP(app)
            fitModel(app)
            updateFit(app)
            
        end

        % Value changed function: PrebleachSpinner
        function PrebleachSpinnerValueChanged(app, event)
            value = app.PrebleachSpinner.Value;
            app.FRAP.PreBleachTimeIdx = find(seconds(app.DATA.t) == value);
            if isgraphics(app.ROI.Bleach.handle)
                updateSignal(app,[1:app.DATA.DimT],'Bleach')
            end
            if isgraphics(app.ROI.Reference.handle)
                updateSignal(app,[1:app.DATA.DimT],'Reference')
            end
            if isgraphics(app.ROI.Background.handle)
                updateSignal(app,[1:app.DATA.DimT],'Background')
            end
            if isgraphics(app.FRAP.graph_meas)
                computeFRAP(app)
                updateFRAP(app)
                fitModel(app)
                updateFit(app)
            end
            app.UIAxes_Graphs.XLim    = seconds([app.DATA.t(1) app.DATA.t(end)]-app.DATA.t(app.FRAP.PreBleachTimeIdx+1));
            app.UIAxes_Residuals.XLim = seconds([app.DATA.t(1) app.DATA.t(end)]-app.DATA.t(app.FRAP.PreBleachTimeIdx+1));
        end

        % Value changed function: ChanneltouseDropDown
        function ChanneltouseDropDownValueChanged(app, event)
            app.FRAP.idxChUsed = find(ismember(app.ChanneltouseDropDown.Items,app.ChanneltouseDropDown.Value));
            app.hImage.CData = app.DATA.Stack(:,:,app.CurrentTidx,app.FRAP.idxChUsed);
            im = app.DATA.Stack(:,:,:,app.FRAP.idxChUsed);
            im = im(:);
            app.UIAxes_Images.CLim = [min(im) max(im)];
            
            
            if isgraphics(app.ROI.Bleach.handle)
                extractSignal(app,[1:app.DATA.DimT],'Bleach');
                updateSignal(app,[1:app.DATA.DimT],'Bleach');
            end
            if isgraphics(app.ROI.Reference.handle)
                extractSignal(app,[1:app.DATA.DimT],'Reference');
                updateSignal(app,[1:app.DATA.DimT],'Reference');
            end
            if isgraphics(app.ROI.Background.handle)
                extractSignal(app,[1:app.DATA.DimT],'Background');
                updateSignal(app,[1:app.DATA.DimT],'Background');
            end
            if isgraphics(app.FRAP.graph_meas)
                computeFRAP(app)
                updateFRAP(app)
                fitModel(app)
                updateFit(app)
            end
            
            %             computeFRAP(app);
            
            
        end

        % Value changed function: SwitchRawFRAP
        function SwitchRawFRAPValueChanged(app, event)
            value = app.SwitchRawFRAP.Value;
            switch value
                case 'Raw'
                    if isgraphics(app.ROI.Bleach.graph)
                        app.ROI.Bleach.graph.Visible = 'on';
                    end
                    if isgraphics(app.ROI.Reference.graph)
                        app.ROI.Reference.graph.Visible = 'on';
                    end
                    if isgraphics(app.ROI.Background.graph)
                        app.ROI.Background.graph.Visible = 'on';
                    end
                    app.FRAP.graph_meas.Visible  = 'off';
                    app.FRAP.graph_fit.Visible    = 'off';
                    app.FRAP.graph_residuals.Visible   = 'off';
                    app.UIAxes_Residuals.Visible = 'off';
                case 'FRAP'
                    
                    if isgraphics(app.ROI.Bleach.graph)
                        app.ROI.Bleach.graph.Visible = 'off';
                    end
                    if isgraphics(app.ROI.Reference.graph)
                        app.ROI.Reference.graph.Visible = 'off';
                    end
                    if isgraphics(app.ROI.Background.graph)
                        app.ROI.Background.graph.Visible = 'off';
                    end
                    app.FRAP.graph_meas.Visible      = 'on';
                    app.FRAP.graph_fit.Visible       = 'on';
                    app.FRAP.graph_residuals.Visible = 'on';
                    app.UIAxes_Residuals.Visible     = 'on';
            end
            
            
        end

        % Selection changed function: ModelButtonGroup
        function ModelButtonGroupSelectionChanged(app, event)
            %             selectedButton = app.ModelButtonGroup.SelectedObject;
            switch app.ModelButtonGroup.SelectedObject.Text
                case 'One binding state'
                    headers = {'Bleach depth';...
                        'Half-time (s)';...
                        'Mobile fraction';...
                        'Binding rate (1/s)';...
                        '';...
                        'adjusted-R2';
                        'Chi2';
                        'Q'};
                    fitModel(app)
                    
                    if isempty(app.FRAP.fit.Param)
                        data = [headers cell(size(headers,1),1)];
                        app.UITableFitData.Data = data;
                    else
                        data{1,1} =  app.FRAP.fit.Param.BleachDepth;
                        data{2,1} =  app.FRAP.fit.Param.HalfLife;
                        data{3,1} =  app.FRAP.fit.Param.MobileFrac;
                        data{4,1} =  app.FRAP.fit.Param.BindingRate;
                        
                        data{6,1} =  app.FRAP.fit.gof.adjrsquare;
                        data{7,1} =  app.FRAP.fit.Param.Chi2;
                        data{8,1} =  app.FRAP.fit.Param.Q;
                        app.UITableFitData.Data = [headers data];
                    end
                    
                case 'Two binding states'
                    headers = {'Bleach depth';...
                        'Half-time (s)';...
                        'Mobile fraction slow';...
                        'Binding rate slow (1/s)';...
                        'Mobile fraction fast';...
                        'Binding rate fast (1/s)';...
                        '';...
                        'adjusted-R2';
                        'Chi2';
                        'Q'};
                    fitModel(app)
                    if isempty(app.FRAP.fit.Param)
                        data = [headers cell(size(headers,1),1)];
                        app.UITableFitData.Data = data;
                    else
                        data{1,1} =  app.FRAP.fit.Param.BleachDepth;
                        data{2,1} =  app.FRAP.fit.Param.HalfLife;
                        data{3,1} =  app.FRAP.fit.Param.MobileFrac1;
                        data{4,1} =  app.FRAP.fit.Param.BindingRate1;
                        data{5,1} =  app.FRAP.fit.Param.MobileFrac2;
                        data{6,1} =  app.FRAP.fit.Param.BindingRate2;
                        
                        data{8,1} =  app.FRAP.fit.gof.adjrsquare;
                        data{9,1} =  app.FRAP.fit.Param.Chi2;
                        data{10,1} =  app.FRAP.fit.Param.Q;
                        app.UITableFitData.Data = [headers data];
                    end
                case 'Diffusion'
                    headers = {'Half-time (s)';...
                        'Mobile fraction';...
                        'Diffusion time (s)';...
                        ['Diffusion coeff (' char(181) 'm' char(178) '/s)'];...
                        '';...
                        'adjusted-R2';
                        'Chi2';
                        'Q'};
                    fitModel(app)
                    if isempty(app.FRAP.fit.Param)
                        data = [headers cell(size(headers,1),1)];
                        app.UITableFitData.Data = data;
                    else
                        data{1,1} =  app.FRAP.fit.Param.BleachDepth;
                        data{2,1} =  app.FRAP.fit.Param.HalfLife;
                        data{3,1} =  app.FRAP.fit.Param.tauD;
                        data{4,1} =  app.FRAP.fit.Param.D;
                        
                        data{6,1} =  app.FRAP.fit.gof.adjrsquare;
                        data{7,1} =  app.FRAP.fit.Param.Chi2;
                        data{8,1} =  app.FRAP.fit.Param.Q;
                        app.UITableFitData.Data = [headers data];
                    end
            end


            updateFit(app)
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [~,fname] = fileparts(app.DATA.FileName);
            DATA = app.DATA;
            FRAP = app.FRAP;
            ROI  = app.ROI;
%             [FileName,PathName,idx] = uiputfile('*.mat','Save',fullfile(app.DATA.FilePath,fname))
%             if isnumeric(FileName)
%                 return
%             end
            uisave({'DATA','ROI','FRAP'},fullfile(app.DATA.FilePath,fname))
            headers = {'Time (s)','Bleach','Reference','Background','FRAP','Fit','Residuals'};
            data(:,1) = num2cell(seconds(app.DATA.t'-app.DATA.t(app.FRAP.PreBleachTimeIdx+1)));
            if ~isempty(ROI.Bleach)
                data(:,2) = num2cell(ROI.Bleach.Signal);               
            end
            if ~isempty(ROI.Reference)
                data(:,3) = num2cell(ROI.Reference.Signal);               
            end
            if ~isempty(ROI.Background)
                data(:,4) = num2cell(ROI.Background.Signal);               
            end
            if ~isempty(FRAP.measurement)
                data(:,5) = num2cell(app.FRAP.measurement);               
            end
            if ~isempty(FRAP.fit)
                t = seconds(app.DATA.t);
                t = t-t(app.FRAP.PreBleachTimeIdx+1);
                idxKeep = t>=0;
                
                t = t(idxKeep);
                data(find(idxKeep,1):end,6) = num2cell(FRAP.fit.func(t));
                data(find(idxKeep,1):end,7) = num2cell(FRAP.fit.residuals);
            end
            data = [headers;data];
            [FileName,PathName,idx] = uiputfile('*.xlsx','Save',fullfile(app.DATA.FilePath,fname));
            if isnumeric(FileName)
                return
            end
            writecell(data,fullfile(PathName,FileName),'Sheet','Traces')
            writecell(app.UITableFitData.Data,fullfile(PathName,FileName),'Sheet','Parameters')
            
            
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [FileName,FilePath] = uigetfile({'*.mat','FRAP analysis files'},...
                'Select a FRAP analysis',...
                app.DefaultOptions.LastFolderPath,...
                'MultiSelect', 'off');
            if isnumeric(FileName)
                return
            end
            app.DefaultOptions.LastFolderPath = FilePath;
            DefaultOptions = app.DefaultOptions;
            save("mfiles\DefaultOptions.mat",'DefaultOptions')
            
            
            hwb = uiprogressdlg(app.FRAPUIFigure,'Title',['Loading ' FileName],...
                'Indeterminate','on');
            
            %
            data = load(fullfile(FilePath,FileName));
            app.DATA = data.DATA;
            app.ROI  = data.ROI;
            app.FRAP = data.FRAP;
            resetFRAP(app)
            close(hwb)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FRAPUIFigure = uifigure('Visible', 'off');
            app.FRAPUIFigure.Name = 'FRAP';
            app.FileMenu = uimenu(app.FRAPUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Accelerator = 'L';
            app.LoadMenu.Text = 'Load';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Separator = 'on';
            app.ExportMenu.Accelerator = 'E';
            app.ExportMenu.Text = 'Export';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.EditMenu = uimenu(app.FRAPUIFigure);
            app.EditMenu.Text = 'Edit';
            app.CorrectdriftMenu = uimenu(app.EditMenu);
            app.CorrectdriftMenu.MenuSelectedFcn = createCallbackFcn(app, @CorrectdriftMenuSelected, true);
            app.CorrectdriftMenu.Text = 'Correct drift';
            app.GridLayout = uigridlayout(app.FRAPUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ROITab = uitab(app.TabGroup);
            app.ROITab.Title = 'ROI';
            app.AddBleachButton = uibutton(app.ROITab, 'push');
            app.AddBleachButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddBleachButton.Tag = 'Bleach';
            app.AddBleachButton.Text = 'Add Bleach';
            app.AddReferenceButton = uibutton(app.ROITab, 'push');
            app.AddReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddReferenceButton.Tag = 'Reference';
            app.AddReferenceButton.Text = 'Add Reference';
            app.AddBackgroundButton = uibutton(app.ROITab, 'push');
            app.AddBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddBackgroundButton.Tag = 'Background';
            app.AddBackgroundButton.Text = 'Add Background';
            app.LockSwitchLabel = uilabel(app.ROITab);
            app.LockSwitchLabel.Text = 'Lock';
            app.LockBleachSwitch = uiswitch(app.ROITab, 'slider');
            app.LockBleachSwitch.Tag = 'Bleach';
            app.LockBleachSwitch.Value = 'On';
            app.LockSwitchLabel_2 = uilabel(app.ROITab);
            app.LockSwitchLabel_2.Text = 'Lock';
            app.LockReferenceSwitch = uiswitch(app.ROITab, 'slider');
            app.LockReferenceSwitch.Tag = 'Reference';
            app.LockReferenceSwitch.Value = 'On';
            app.LockSwitchLabel_3 = uilabel(app.ROITab);
            app.LockSwitchLabel_3.Text = 'Lock';
            app.LockBackgroundSwitch = uiswitch(app.ROITab, 'slider');
            app.LockBackgroundSwitch.Tag = 'Background';
            app.LockBackgroundSwitch.Value = 'On';
            app.ChanneltouseDropDownLabel = uilabel(app.ROITab);
            app.ChanneltouseDropDownLabel.Text = 'Channel to use';
            app.ChanneltouseDropDown = uidropdown(app.ROITab);
            app.ChanneltouseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChanneltouseDropDownValueChanged, true);
            app.ExperimentsTab = uitab(app.TabGroup);
            app.ExperimentsTab.Title = 'Experiments';
            app.SwitchRawFRAP = uiswitch(app.ExperimentsTab, 'slider');
            app.SwitchRawFRAP.Items = {'Raw', 'FRAP'};
            app.SwitchRawFRAP.ValueChangedFcn = createCallbackFcn(app, @SwitchRawFRAPValueChanged, true);
            app.SwitchRawFRAP.Value = 'Raw';
            app.PrebleachSpinnerLabel = uilabel(app.ExperimentsTab);
            app.PrebleachSpinnerLabel.Text = 'Pre-bleach';
            app.PrebleachSpinner = uispinner(app.ExperimentsTab);
            app.PrebleachSpinner.ValueDisplayFormat = '%11.4g s';
            app.PrebleachSpinner.ValueChangedFcn = createCallbackFcn(app, @PrebleachSpinnerValueChanged, true);
            app.PrebleachSpinner.Value = 4;
            app.NormalizationButtonGroup = uibuttongroup(app.ExperimentsTab);
            app.NormalizationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizationButtonGroupSelectionChanged, true);
            app.NormalizationButtonGroup.Title = 'Normalization';
            app.NoneButton = uiradiobutton(app.NormalizationButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.SingleButton = uiradiobutton(app.NormalizationButtonGroup);
            app.SingleButton.Text = 'Single';
            app.DoubleButton = uiradiobutton(app.NormalizationButtonGroup);
            app.DoubleButton.Text = 'Double';
            app.UIAxesNormEqu = uiaxes(app.ExperimentsTab);
            title(app.UIAxesNormEqu, 'Title')
            app.FitTab = uitab(app.TabGroup);
            app.FitTab.Title = 'Fit';
            app.ModelButtonGroup = uibuttongroup(app.FitTab);
            app.ModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelButtonGroupSelectionChanged, true);
            app.ModelButtonGroup.Title = 'Model';
            app.OnebindingstateButton = uiradiobutton(app.ModelButtonGroup);
            app.OnebindingstateButton.Text = 'One binding state';
            app.OnebindingstateButton.Value = true;
            app.TwobindingstatesButton = uiradiobutton(app.ModelButtonGroup);
            app.TwobindingstatesButton.Text = 'Two binding states';
            app.DiffusionButton = uiradiobutton(app.ModelButtonGroup);
            app.DiffusionButton.Text = 'Diffusion';
            app.UITableFitData = uitable(app.FitTab);
            app.UITableFitData.ColumnName = '';
            app.UITableFitData.RowName = {};
            app.TimePanel = uipanel(app.GridLayout);
            app.TimePanel.Title = 'Time';
            app.TimeSpinner = uispinner(app.TimePanel);
            app.TimeSpinner.ValueDisplayFormat = '%11.4g s';
            app.TimeSlider = uislider(app.TimePanel);
            app.TimeSlider.MajorTicks = [];
            app.TimeSlider.MinorTicks = [];
            app.UIAxes_Images = uiaxes(app.GridLayout);
            title(app.UIAxes_Images, 'Title')
            app.UIAxes_Images.XTick = [];
            app.UIAxes_Images.YTick = [];
            app.UIAxes_Images.ZTick = [];
            app.UIAxes_Graphs = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_Graphs, 'Time (s)')
            ylabel(app.UIAxes_Graphs, 'Y')
            zlabel(app.UIAxes_Graphs, 'Z')
            app.UIAxes_Residuals = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_Residuals, 'Time (s)')
            ylabel(app.UIAxes_Residuals, 'Residuals')
            % Show the figure after all components are created
            app.FRAPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FRAP_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FRAPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FRAPUIFigure)
        end
    end
end